classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        EcouterlesonButton   matlab.ui.control.Button
        RSBListBox           matlab.ui.control.ListBox
        RSBListBoxLabel      matlab.ui.control.Label
        SignaltraitButton    matlab.ui.control.Button
        AjoutdusignalButton  matlab.ui.control.Button
        AjoutdubruitButton   matlab.ui.control.Button
        RightPanel           matlab.ui.container.Panel
        UIAxes               matlab.ui.control.UIAxes
        ContextMenu          matlab.ui.container.ContextMenu
        Menu                 matlab.ui.container.Menu
        Menu2                matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        sig=load("fcno03fz.mat") % Description
        RSB;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AjoutdusignalButton
        function AjoutdusignalButtonPushed(app, event)
            s1 = -app.sig.fcno03fz;
            n=length(s1);
            plot(app.UIAxes,s1,'g');
            hold(app.UIAxes,"on");
        end

        % Button pushed function: AjoutdubruitButton
        function AjoutdubruitButtonPushed(app, event)
            s1 = -app.sig.fcno03fz;
            [sigbruit,bruit]=parolebruitee(s1,5);
            plot(app.UIAxes,sigbruit,'r');
            hold(app.UIAxes,"on");
            plot(app.UIAxes,s1,'g');
            hold(app.UIAxes,"on");
        end

        % Button pushed function: SignaltraitButton
        function SignaltraitButtonPushed(app, event)
            s1 = -app.sig.fcno03fz;
            [sigbruit,bruit]=parolebruitee(s1,5);
            N=64;
            prct=50;
            dec=decomp(s1,N,prct);
            
            [fen,w]=fenetre(dec,"hamming");

            RSB = 5;

            [sigbruite, bruit1] = parolebruitee(s1, RSB);

            dec2=decomp(sigbruite,N,prct);
            %[m,k] = size(dec2);
            
            [fenb,wb]=fenetre(dec2,"hamming");

            for i=1:126
                res(:,i) = traitement_trame(fenb(:,i), sigbruite);
            end


            Signal_final=AddRecouv(real(res),w,prct);
            Signal_finalf=[zeros(1,(length(s1)-length(Signal_final))/2) Signal_final]; 
            

            plot(app.UIAxes,sigbruit,'r');
            hold(app.UIAxes,"on");
            plot(app.UIAxes,s1,'g');
            hold(app.UIAxes,"on");
            plot(app.UIAxes,Signal_finalf,'b');
            hold(app.UIAxes,"on");
        end

        % Clicked callback: RSBListBox
        function RSBListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            global item;
        end

        % Value changed function: RSBListBox
        function RSBListBoxValueChanged(app, event)
            RSB = app.RSBListBox.Value;
        end

        % Button pushed function: EcouterlesonButton
        function EcouterlesonButtonPushed(app, event)
            s1 = -app.sig.fcno03fz;
            soundsc(s1);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {584, 584};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AjoutdubruitButton = uibutton(app.LeftPanel, 'push');
            app.AjoutdubruitButton.ButtonPushedFcn = createCallbackFcn(app, @AjoutdubruitButtonPushed, true);
            app.AjoutdubruitButton.Text = 'Ajout du bruit';
            app.AjoutdusignalButton = uibutton(app.LeftPanel, 'push');
            app.AjoutdusignalButton.ButtonPushedFcn = createCallbackFcn(app, @AjoutdusignalButtonPushed, true);
            app.AjoutdusignalButton.Text = 'Ajout du signal';
            app.SignaltraitButton = uibutton(app.LeftPanel, 'push');
            app.SignaltraitButton.ButtonPushedFcn = createCallbackFcn(app, @SignaltraitButtonPushed, true);
            app.SignaltraitButton.Text = 'Signal traité';
            app.RSBListBoxLabel = uilabel(app.LeftPanel);
            app.RSBListBoxLabel.Text = 'RSB';
            app.RSBListBox = uilistbox(app.LeftPanel);
            app.RSBListBox.Items = {'5', '10', '15', ''};
            app.RSBListBox.ValueChangedFcn = createCallbackFcn(app, @RSBListBoxValueChanged, true);
            app.RSBListBox.ClickedFcn = createCallbackFcn(app, @RSBListBoxClicked, true);
            app.RSBListBox.Value = '5';
            app.EcouterlesonButton = uibutton(app.LeftPanel, 'push');
            app.EcouterlesonButton.ButtonPushedFcn = createCallbackFcn(app, @EcouterlesonButtonPushed, true);
            app.EcouterlesonButton.Text = 'Ecouter le son';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Signal')
            xlabel(app.UIAxes, 'Axe temporel')
            ylabel(app.UIAxes, 'Axe de l''amplitude')
            zlabel(app.UIAxes, 'Z')
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.AjoutdubruitButton.ContextMenu = app.ContextMenu;
            app.SignaltraitButton.ContextMenu = app.ContextMenu;
            app.EcouterlesonButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end